classdef BasinsOfAttraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasinsofAttractionUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel_3                     matlab.ui.container.Panel
        ReadyLamp                   matlab.ui.control.Lamp
        ReadyLampLabel              matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
        ResetAxesButton             matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        PolynomialTab               matlab.ui.container.Tab
        PolyTable                   matlab.ui.control.Table
        PolyLabel                   matlab.ui.control.Label
        CurrentPolynomialLabel      matlab.ui.control.Label
        DegreeSpinner               matlab.ui.control.Spinner
        DegreeSpinnerLabel          matlab.ui.control.Label
        RandPolyButton              matlab.ui.control.Button
        ColorsTab                   matlab.ui.container.Tab
        ColormapDropDown            matlab.ui.control.DropDown
        ColormapDropDownLabel       matlab.ui.control.Label
        RootColorButton             matlab.ui.control.Button
        DivergenceColorButton       matlab.ui.control.Button
        IndeterminateColorButton    matlab.ui.control.Button
        RootColorLabel              matlab.ui.control.Label
        DivergenceColorLabel        matlab.ui.control.Label
        IndeterminateColorLabel     matlab.ui.control.Label
        RootsandcorrespondingBasinColorsLabel  matlab.ui.control.Label
        BasinPanel                  matlab.ui.container.Panel
        SettingsTab                 matlab.ui.container.Tab
        ShowprogressCheckBox        matlab.ui.control.CheckBox
        GridSettingsLabel           matlab.ui.control.Label
        AnimationSettingsLabel      matlab.ui.control.Label
        IterationSettingsLabel      matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        WaitSpinner                 matlab.ui.control.Spinner
        WaitsecSpinnerLabel         matlab.ui.control.Label
        AnimateCheckBox             matlab.ui.control.CheckBox
        IterTable                   matlab.ui.control.Table
        MinorGridCheckBox           matlab.ui.control.CheckBox
        GridCheckBox                matlab.ui.control.CheckBox
        GridTable                   matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        % Polynomial
        Degree {mustBePositive}; % Degree of the polynomial
        Coeffs(:,1) double; % Polynomial coefficients
        Roots(:,1) double; % Roots of the polynomial
        NRoots {mustBePositive}; % Number of roots
        PolyStr string; % Current polynomial in string format
        PolyStrLtx string; % Current polynomial in string AND latex format
        
        % Grid
        Grid(:,:) double; % M x N grid of initial values
        xLim(1,2) double; % Limits for the x (real) axis
        yLim(1,2) double; % Limits for the y (imaginary) axis
        M {mustBePositive}; % Number of grid points in the x direction
        N {mustBePositive}; % Number of grid points in the y direction
        Re(:,1) double % Real axis
        Im(:,1) double % Imaginary axis
        
        % Iteration
        Tol double {mustBePositive}; % Tolerance for corvengence
        Bound double {mustBePositive}; % Bound for divergence
        IterMax {mustBePositive}; % Maximum # of iterations
        ItersPerStep {mustBePositive}; % Iterations per animation step
        Iters {mustBeNonnegative}; % Current number of iterations
                 
        % Colors
        DivergeColor(1,3) double % Color for divergence
        IndeterminateColor(1,3) double % Color for non-convergent and non-divergent points
        RootColor(1,3) double % Color for plotting the roots
        CMap(:,3) % Current colormap
        
        % State and Animation
        State string; % Current state of program
        ShowProgress logical; % Boolean for showing progress
        Animate logical; % Boolean for animation
        AnimWait double {mustBePositive} % Animation loop length in seconds
      
    end % properties
    
    methods (Access = private)
        
        function initPoly(app)
            % INITPOLY  Initialize the polynomial data and properties.
            %
            % The initial values for the polynomial are taken from the
            % project instructions as a reference.
            %
            % Note that the degree of the polynomial should be at least 1
            % to get meaningful results. Cases where the degree is 1 or 2
            % are not that interesting.
            %
            % See also UPDATEPOLY.
            
            app.Degree = 3;
            app.DegreeSpinner.Value = app.Degree;
            app.PolyTable.Data = [1;0;0;-1];
            app.PolyTable.RowName = 'numbered';
            app.updatePoly;
            
        end % initPoly
        
        function initColor(app)
            % INITCOLOR  Initialize the color data and properties.
            %
            % See also UPDATECOLORMAP.
            
            app.DivergeColor = [0,0,0];
            app.IndeterminateColor = [1,1,1];
            app.RootColor = [1,1,0];
            app.DivergenceColorButton.BackgroundColor = app.DivergeColor;
            app.IndeterminateColorButton.BackgroundColor = app.IndeterminateColor;
            app.RootColorButton.BackgroundColor = app.RootColor;
            
            % Predefined colormaps
            app.ColormapDropDown.Items = {'parula','turbo','hsv', ...
                'hot','cool','spring','summer','autumn','winter', ...
                'pink','lines','jet', ...
                'prism'};
            app.ColormapDropDown.ItemsData = {@parula,@turbo,@hsv, ...
                @hot,@cool,@spring,@summer,@autumn,@winter, ...
                @pink,@lines,@jet, ...
                @prism};
            
            app.updateColorMap;
            
        end % initColor
        
        function initSettings(app)
            % INITSETTINGS  Initialize the grid, iteration and animation 
            % data and properties.
            %
            % See also UPDATEGRID. 
            
            % Grid
            app.xLim = [-1.5,1.5];
            app.yLim = [-1.5,1.5];
            app.M = 1000;
            app.N = 1000;
            
            % Iteration
            app.Tol = 0.001;
            app.Bound = 100;
            app.IterMax = 10;
            app.ItersPerStep = 1;
            
            % Animation
            app.Animate = true;
            app.AnimWait = 0.1; % Note the lower limit for WaitSpinner.

            % Initialize Grid and Iteration Tables
            app.GridTable.Data(1,:) = [app.xLim,app.M];
            app.GridTable.Data(2,:) = [app.yLim,app.N];
            app.GridTable.RowName = {'x','y'};
            app.IterTable.Data = [app.Tol; ...
                                  app.Bound; ...
                                  app.IterMax; ...
                                  app.ItersPerStep];
            app.IterTable.RowName = {'Tolerance','Bound','Max','Step'};
            
            % Initialize Animation settings
            app.WaitSpinner.LowerLimitInclusive = true;
            app.WaitSpinner.Limits = [0.1,10];
            app.WaitSpinner.Value = app.AnimWait;
            app.WaitSpinner.Step = 0.1;
            app.AnimateCheckBox.Value = app.Animate;
            
            app.updateGrid;
            
        end % initSettings
        
        function setAxes(app,reset)
            % SETAXES  Set or reset current axes settings.
            %
            % reset = boolean for clearing the current axes (false)
            %
            % By default, setAxes only sets the title, limits, labels,
            % box and grid settings for the current UIAxes according to
            % the current properties. The title is not in latex format.
            % The axes are cleared if reset == true.
            %
            % Too long polynomials do not fit to the title properly. This 
            % is only a visual flaw but it does not affect the program 
            % execution. 
            %
            % See also SETPOLYSTR.
            
            if nargin < 2
                reset = false; % Default value
            end
            
            str = app.PolyStr; % String of the current polynomial
            
            if reset
                cla(app.UIAxes,"reset");
                str = ' ';
            end
            
            app.UIAxes.Title.String = str;
            app.UIAxes.TitleFontSizeMultiplier = 1.5;
            set(app.UIAxes,'XLim',app.xLim,'YLim',app.yLim);
            set(app.UIAxes,'Box','on');
            app.UIAxes.XLabel.String = "Re";
            app.UIAxes.YLabel.String = "Im";
            
            if app.GridCheckBox.Value
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
            else
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
            end
            
            if app.MinorGridCheckBox.Value
                app.UIAxes.XMinorGrid = 'on';
                app.UIAxes.YMinorGrid = 'on';
            else
                app.UIAxes.XMinorGrid = 'off';
                app.UIAxes.YMinorGrid = 'off';
            end
            
        end % setAxes
        
        function checkForDataErrorsAndUpdate(app)
            % CHECKFORDATAERRORSANDUPDATE  Check for erroneous data and
            % update.
            %
            % If the data is erroneous, the State of the program is set to
            % the corresponding error state. Otherwise, all necessary
            % updates are executed. 
            % 
            % The state of the program is set to 'ready' if there are
            % no errors. Any errors are catched in the corresponding check
            % functions that are also responsible for toggling the state
            % of the program accordingly.
            % 
            % See also CHECKVALIDPOLY, CHECKVALIDGRID, CHECKVALIDITER,
            % UPDATEPOLY, UPDATECOLORMAP, UPDATEBASINPANEL, UPDATESETTINGS,
            % TOGGLESTATE.
            
            if app.checkValidPoly && app.checkValidGrid && app.checkValidIter
                app.updatePoly;
                app.updateColorMap;
                app.updateSettings;
                app.toggleState('ready');
            end
            
        end % checkForDataErrorsAndUpdate
        
        function res = checkValidPoly(app)
            % CHECKVALIDPOLY  Check for errors in polynomial data.
            %
            % Polynomial data is valid if all of the coefficients given in
            % the PolyTable are numerical and the leading coefficient is
            % nonzero.
            %
            % If there is an error, the label for current polynomial is
            % erased and the state of the program is set according to 
            % the error.
            %
            % Function returns true if the data is valid and false
            % otherwise.
            %
            % See also ISVALIDDATA, TOGGLESTATE.
            
            isValid = true;
            
            if ~app.isValidData(app.PolyTable.Data)
                app.toggleState('nanError')
                isValid = false;
            elseif app.PolyTable.Data(1) == 0
                app.toggleState('coeffError')
                isValid = false;
            end
            
            % Polynomial cannot be generated from erroneous data.
            if ~isValid
                app.PolyLabel.Text = '';
            end
            
            res = isValid;

        end % checkValidPoly
        
        function res = checkValidGrid(app)
            % CHECKVALIDGRID  Check for errors in grid data.
            %
            % Grid data is valid if all values are numerical. The number of
            % grid points for each axis must be a positive whole number.
            % Furthermore, the MIN limit must be less than MAX.
            %
            % If there is an error, the state of the program is set
            % accordingly.
            %
            % Function returns true if the data is valid and false
            % otherwise.
            %
            % See also ISVALIDDATA, TOGGLESTATE.
            
            res = false;
            
            % Data represented with doubles.
            doubles = app.GridTable.Data(:,1:2);
            
            % Data represented with positive integers
            ints = app.GridTable.Data(:,3);
            
            if ~(app.isValidData(doubles) && app.isValidData(ints))
                app.toggleState('nanError');
                return
            end
            
            if ~app.isValidData(ints,'int')
                app.toggleState('intError');
                return
            end
            
            if ~app.isValidData(ints,'pos')
                app.toggleState('posError')
                return
            end
            
            if ~(doubles(1,1) < doubles(1,2) && doubles(2,1) < doubles(2,2))
                app.toggleState('gridLimError')
                return
            end
            
            res = true;

        end % checkValidGrid
        
        function res = checkValidIter(app)
            % CHECKVALIDITER  Check for errors in iteration data.
            %
            % Iteration data is valid if all of the data is numerical. The
            % maximum number of iterations and iteration step must be
            % positive whole numbers. Furthermore, the tolerance for 
            % convergence must not exceed the bound for divergence, and
            % the maximum number of iterations must be greater than the
            % iteration step.
            %
            % If there is an error, the state of the program is set
            % accordingly.
            %
            % Function returns true if the data is valid and false
            % otherwise.
            %
            % See also ISVALIDDATA, TOGGLESTATE.
            
            res = false;
            doubles = app.IterTable.Data(1:2,:);
            ints = app.IterTable.Data(3:4,:);
            
            if ~(app.isValidData(doubles) && app.isValidData(ints))
                app.toggleState('nanError')
                return
            end
            
            if ~(app.isValidData(doubles,'pos') && app.isValidData(ints,'pos'))
                app.toggleState('posError')
                return
            end
            
            if ~app.isValidData(ints,'int')
                app.toggleState('intError')
                return
            end
            
            % Tolerance for convergence must be smaller than bound for
            % divergence.
            if doubles(1,1) >= doubles(2,1)
                app.toggleState('tolError')
                return
            end
            
            % The maximum number of iterations must be greater than the
            % iteration step.
            if ints(1,1) < ints(2,1)
                app.toggleState('iterBoundError')
                return
            end
            
            res = true;
            
        end % checkValidIter
        
        function res = isValidData(~,data,type)
            % ISVALIDDATA  Check data for different kinds of errors.
            %
            % type = character array for the type of error ('nan')
            %
            % Char type has three different options: 'nan', 'int' and 'pos'
            % for NaN type errors, non-integer errors and non-positive
            % errors, respectively.
            %
            % By default, type == 'nan'. After selecting the error type,
            % the data values in a data matrix are checked for this type
            % of errors.
            %
            % Function returns true if the data is valid and false
            % otherwise. Note that function returns false immediately after
            % finding an error.
            
            res = true;
            
            if nargin < 3
                type = 'nan'; % Default value
            end
            
            switch type
                case 'nan'
                    isFaulty = @(d) isnan(d);
                case 'int'
                    isFaulty = @(d) d - floor(d) ~= 0;
                case 'pos'
                    isFaulty = @(d) d <= 0;
            end
            
            dims = size(data); 
            for j = 1:dims(1)
                for i = 1:dims(2)
                    d = data(j,i);
                    if isFaulty(d)
                        res = false;
                        return
                    end
                end
            end
            
        end % isValidData
        
        function toggleState(app,state)
            % TOGGLESTATE  Toggle the current state of the program.
            %
            % state = character array for the current state of the program
            %
            % Function sets the property app.State to the given state.
            % Depending on the state, ReadyLamp color is set accordingly
            % and the user is notified of the current state via ReadyLampLabel
            % text. Note that ReadyLampLabel text for iteration can also be
            % modified via the function showIterationProgress.
            %
            % Possible states should be easily interpreted from the
            % corresponding char arrays. Error states end in 'Error' and
            % other states are descriptive. The program is ready to calculate
            % the basins of attraction when the state is 'ready'. All error
            % states prohibit the calculations to avoid crashing and they 
            % should be dealt with the way the error messages imply.
            %
            % The current state is changed to state only if it is
            % different to app.State. Note that the Start and Reset Axes
            % Buttons are managed via this function as well as the editability
            % of tables and settings. At the end, a brief pause is necessary
            % to enable MATLAB to update the ReadyLampLabel text correctly.
            %
            % See also MANAGEBUTTONS, DISABLEEDITING, SHOWITERATIONPROGRESS.
            
            if isequal(app.State,state)
                return
            end
            
            app.State = state;
            app.manageButtons(state);
            
            % Different colors for ReadyLamp
            errorColor = [1,0,0];
            waitColor = [1,1,0];
            readyColor = [0,1,0];
            
            switch state
                case 'nanError'
                    app.ReadyLampLabel.Text = 'Fix NaN data.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'intError'
                    app.ReadyLampLabel.Text = 'Fix NON-INTEGER data.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'posError'
                    app.ReadyLampLabel.Text = 'Fix NON-POSITIVE data.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'coeffError'
                    app.ReadyLampLabel.Text = 'Fix leading zero COEFFICIENT.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'gridLimError'
                    app.ReadyLampLabel.Text = 'Fix GRID LIMITS.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'tolError'
                    app.ReadyLampLabel.Text = 'Fix TOLERANCE and BOUND.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'iterBoundError'
                    app.ReadyLampLabel.Text = 'Fix iteration MAX and STEP.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'colorError'
                    app.ReadyLampLabel.Text = 'Fix basin COLORS.';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'init'
                    app.ReadyLampLabel.Text = 'Initializing...';
                    app.ReadyLamp.Color = waitColor;
                    
                case 'iterate'
                    app.ReadyLampLabel.Text = 'Iterating...';
                    app.ReadyLamp.Color = waitColor;
                    app.disableEditing(true);
                    
                case 'abort'
                    app.ReadyLampLabel.Text = 'Aborting...';
                    app.ReadyLamp.Color = waitColor;
                    
                case 'tryAgain'
                    app.ReadyLampLabel.Text = 'Try again!';
                    app.ReadyLamp.Color = errorColor;
                    
                case 'ready'
                    app.ReadyLampLabel.Text = 'Ready';
                    app.ReadyLamp.Color = readyColor;
                    app.disableEditing(false);
                    
            end % switch
            
            pause(0.001) % DO NOT REMOVE!
            
        end % toggleState
        
        function updatePoly(app)
            % UPDATEPOLY  Update the polynomial data.
            %
            % Updating includes the coefficients and roots of the polynomial
            % as well as the string to represent the polynomial in labels 
            % and titles.
            %
            % Note that the polynomial is presented in latex format in
            % label texts. Too long polynomials do not fit the label
            % properly. This is only a visual flaw and it does not affect
            % the program execution.
            %
            % See also INITPOLY, UPDATECOEFFSANDROOTS, SETPOLYSTR.
            
            app.updateCoeffsAndRoots;
            app.setPolyStr;
            app.PolyLabel.Text = app.PolyStrLtx;
            
        end % updatePoly
        
        function updateCoeffsAndRoots(app)
            % UPDATECOEFFSANDROOTS  Update the coefficients and roots of
            % the current polynomial.

            app.Coeffs = app.PolyTable.Data;
            app.Roots = roots(app.Coeffs);
            app.NRoots = length(app.Roots); % Number of roots
            
        end % updateCoeffsAndRoots
        
        function updateColorMap(app)
            % UPDATECOLORMAP  Update the colormap to the user selected
            % predefined colormap.
            %
            % The colormap is obtained from predefined colormaps. The
            % colors are selected such that the root indices determine 
            % appropriate colors.
            %
            % Note that the second last and last entries of the colormap are
            % reserved for coloring the diverging and indeterminate initial
            % points, respectively. Any user selected colors will also be 
            % replaced by the selected colormap. If the polynomial settings
            % have not changed since the last plotting, the new changes
            % made in this function are applied to the current UIAxes and 
            % BasinPanel. 
            %
            % See also INITCOLOR, ROOTCOLORDLG,
            % CHECKFORDATAERRORSANDUPDATE, UPDATEBASINPANEL.
            
            % Dropdown values are function handles.
            map = app.ColormapDropDown.Value;
            
            basinColors = map(app.NRoots + 1);
            app.CMap = [basinColors(1:end-1,:); ...
                        app.DivergeColor; ...
                        app.IndeterminateColor];
            app.updateBasinPanel;
            
            if app.PolyStr == app.UIAxes.Title.String
                % The roots and basin colors correspond to the current
                % polynomial.
                colormap(app.UIAxes, app.CMap);
            end
            
        end % updateColorMap
        
        function updateBasinPanel(app)
            % UPDATEBASINPANEL  Update the panel displaying the roots and
            % their corresponding basin colors.
            %
            % The BasinPanel displays the roots and their corresponding
            % basin colors in a visually pleasing and intuitive way on a 
            % scrollable panel. The panel is destroyed and regenerated to 
            % ensure that the roots and colors appear correctly. Four roots
            % are displayed at the same time at most. Roots not seen on the
            % panel can be found by scrolling the panel.
            %
            % Each root value is displayed with the accuracy of three
            % decimal points. The color is shown on a Button, which, when
            % pushed, opens up a uidialog for color selection.
            %
            % Note that the roots are presented in latex format in the
            % label texts.
            %
            % See also COLORBUTTONPUSHED, ROOTCOLORDLG,
            % CHECKFORDATAERRORSANDUPDATE.

            pos = app.BasinPanel.Position; % Store the correct position
            app.BasinPanel.delete;
            
            % Regenerate panel.
            app.BasinPanel = uipanel(app.ColorsTab);
            tooltipStr = ['Change the basin color of a root ' ...
                'manually by pressing the corresponding color ' ...
                'button on the right.'];
            app.BasinPanel.Tooltip = tooltipStr;
            app.BasinPanel.Scrollable = 'on';
            app.BasinPanel.FontSize = 12;
            app.BasinPanel.Position = pos;
            width = pos(3);
            height = pos(4);
            itemSize = 22;
            labelLength = 150;
            yDiff = itemSize / 2; % Horizontal gap between items
            lowerLeftCorner = [0.15*width,yDiff]; % Anchor point
            x = lowerLeftCorner(1);
            
            % If the roots fill up the panel, it is filled differently.
            nroots = app.NRoots;
            isFull = nroots*itemSize + (nroots+1)*yDiff > height;
            
            if nroots ~= 0
                for j = 1:nroots
                    k = nroots - j + 1;
                    
                    if isFull
                        y = lowerLeftCorner(2) + (k-1)*(itemSize+yDiff);
                    else
                        y = (height-yDiff-itemSize) - (j-1)*(itemSize+yDiff);
                    end
                                        
                    if j == 1 && isFull
                        % Create an empty space at the top
                        newLabel = uilabel(app.BasinPanel);
                        newLabel.Position = [x,y+yDiff,labelLength,itemSize];
                        newLabel.Text = '';
                        newLabel.FontSize = 12;
                    end
                    
                    % New label
                    newLabel = uilabel(app.BasinPanel);
                    newLabel.Position = [x,y,labelLength,itemSize];
                    str = ['$z_{',num2str(j),'}=',num2str(app.Roots(j),'%.3f'),'$'];
                    newLabel.Text = str;
                    newLabel.FontSize = 12;
                    newLabel.Interpreter = "latex";
                    
                    % New button
                    btnColor = app.CMap(j,:);
                    newBtn= uibutton(app.BasinPanel);
                    newBtn.Text = '';
                    newBtn.FontSize = 12;
                    newBtn.ButtonPushedFcn = {@app.colorButtonPushed,newBtn,j};
                    newBtn.Position = [x+0.6*width,y,itemSize,itemSize];
                    newBtn.BackgroundColor = btnColor;
                    newBtn.Tooltip = num2str(btnColor);
                    
                end
            end % if
        
        end % updateBasinPanel
        
        function updateSettings(app)
            % UPDATESETTINGS  Update the grid and iteration data and properties.
            %
            % Updating includes the properties for representing the grid
            % and iteration data. Also the grid consisting of initial values
            % is updated by calling the function updateGrid.
            %
            % See also INITSETTINGS, UPDATEGRID.

            % Update Grid
            data = app.GridTable.Data;
            app.xLim = [data(1,1),data(1,2)];
            app.yLim = [data(2,1),data(2,2)];
            app.M = data(1,3);
            app.N = data(2,3);
            app.updateGrid;
                    
            % Update Iteration
            data = app.IterTable.Data;
            app.Tol = data(1,1);
            app.Bound = data(2,1);
            app.IterMax = data(3,1);
            app.ItersPerStep = data(4,1);
            
        end % updateSettings
        
        function updateGrid(app)
            % UPDATEGRID  Update the grid of initial values.
            %
            % The grid of initial values consists of a M x N meshgrid
            % determined by the limits in properties xLim and yLim. The
            % inital values are equally spaced complex numbers within these
            % intervals.
            %
            % In addition to the grid, the real and imaginary axis are also
            % updated. They come in handy when plotting the image for the
            % basins of attraction.
            %
            % See also INITSETTINGS, UPDATESETTINGS.
            
            lim = [app.xLim app.yLim];
            m = app.M;
            n = app.N;
            x = linspace(lim(1),lim(2),m); % real parts
            y = linspace(lim(3),lim(4),n); % imaginary parts
            app.Re = x;
            app.Im = y;
            [X,Y] = meshgrid(x,y);
            app.Grid = X + 1i*Y;
            
        end % updateGrid
        
        function colorButtonPushed(app, ~, ~, button, rootIdx)
            % COLORBUTTONPUSHED  Self-made callback function for color
            % button pushed event.
            %
            % button = the color button being pushed
            % rootIdx = the index for the corresponding root
            %
            % The color of a basin can only be changed if the current state
            % of the program is 'ready'.
            %
            % See also ROOTCOLORDLG.
            
            if isequal(app.State,'ready')
                app.rootColorDlg(button,rootIdx);
            end            
            
        end % colorButtonPushed
        
        function rootColorDlg(app,button,rootIdx)
            % ROOTCOLORDLG  Select the basin color manually for one root.
            %
            % button = the color button being pushed
            % rootIdx = the index for the corresponding root
            %
            % Predefined uisetcolor dialog takes care of manual selection
            % for the basin color. Note that black and white are reserved
            % colors for diverging and indeterminate initial values,
            % respectively. Also, the chosen color must be different from
            % all the other colors. If there is an error, an uialert will
            % pop up to notify the user. Closing the notification results
            % in the callback uialertClosed to set the state properly.
            %
            % The color dialog asks the user to pick a new color as long as
            % the chosen color is invalid. If the user does not select a 
            % color, the previous color is maintained. The color is shown
            % as the background color of the button. Updates to the BasinPanel 
            % are made locally.
            %
            % Note that updateColorMap is not called to preserve the user 
            % selected color. Furthermore, if the polynomial settings have
            % not changed since the plotting, the new color will be applied
            % to the current UIAxes.
            % 
            % See also COLORBUTTONPUSHED, UIALERTCLOSED, UPDATECOLORMAP.
            
            oldColor = button.BackgroundColor;
            
            notvalidColor = true;
            while notvalidColor
                dialogStr = ['Set color for Root ', num2str(rootIdx), '.'];
                color = uisetcolor(oldColor,dialogStr);
                
                % Bring the app to front after selecting the color
                app.BasinsofAttractionUIFigure.Visible = 'off';
                app.BasinsofAttractionUIFigure.Visible = 'on';

                if ~any(all(app.CMap == color,2)) || isequal(color,oldColor)
                    % All basin colors are unique or the user closed the dialog. 
                    notvalidColor = false;
                else
                    app.toggleState('colorError')
                    errormsg = {'The chosen color is already in use.';
                        '';
                        'Note that Black and White are reserved for diverging';
                        'and indeterminate initial values, respectively.'};
                    errortitle = 'Invalid Color! Try again.';
                    uialert(app.BasinsofAttractionUIFigure,errormsg,errortitle, ...
                        'CloseFcn',@app.uialertClosed);
                    
                    % Withhold the loop as long as the dialog is open
                    while ~isequal(app.State,'tryAgain')
                        pause(0.5);
                    end
                end
            end % while
            
            if ~isequal(color,0)
                % The user selected a valid color.
                button.BackgroundColor = color;
                app.CMap(rootIdx,:) = color;
                
                if app.PolyStr == app.UIAxes.Title.String
                    % The roots and basin colors correspond to the current
                    % polynomial.
                    colormap(app.UIAxes, app.CMap);
                end
            end
            
            app.toggleState('ready');
           
        end % rootColorDlg
        
        function uialertClosed(app,~,~)
            % UIALERTCLOSED  Callback function for closing the uialert.
            %
            % The user selected color is still not valid. Therefore, they
            % need to try again. After setting the current state to
            % 'tryAgain', functions returns to the color dialog loop.
            %
            % See also ROOTCOLORDLG.
            
            app.toggleState('tryAgain');
            
        end % UIALERTCLOSED
        
        function adjustPolyTable(app,change)
            % ADJUSTPOLYTABLE  Adjust the size and data of polynomial Table.
            %
            % change = the number of rows to delete (< 0) or add (>= 0)
            %
            % The lowest rows are removed or new rows are added to the bottom 
            % according to change. Old data remains the same whereas new data 
            % is initialized to zeros.
            %
            % Note that adjustPolyTable does not check whether the data is 
            % valid after the change.
            %
            % See also CHECKFORDATAERRORSANDUPDATE.
            
            oldData = app.PolyTable.Data;

            if change >= 0
                newData = [oldData;zeros(change,1)];
                app.PolyTable.Data = newData;
            else
                app.PolyTable.Data = oldData(1:app.Degree+1);
            end
            
        end % adjustPolyTable
        
        function findAndPlotBasins(app)
            % FINDANDPLOTBASINS  Calculate and plot the basins of convergence.
            %
            % The basins of convergence are calculated using Newton's
            % Method. The initial values are taken from the grid and all
            % properties needed are stored in local variables to speed up
            % the calculation process.
            %
            % Note that any edits to data during iteration will not affect 
            % the initial iteration settings. The iterations continue as long
            % as the maximum number of iterations is not surpassed or the 
            % state of the program is 'iterate'.
            %
            % The basins are plotted using predefined image command. The
            % colors represent the convergence and divergence of different
            % initial values. See the documentation for colors for further
            % details. Values that do not converge nor diverge under the
            % iterations are indeterminate. The corresponding colors are
            % stored in a matrix containing the appropriate indices for the
            % current colormap.
            %
            % All initial values are assigned indetermine at first. After 
            % each inner iteration loop, the convergence and divergence is 
            % checked. For determining the convergence or divergence, the
            % user set tolerance and bound are used, respectively. If an
            % initial value seems to converge to more than one root, the
            % color is determined by the last root satifying the convergence
            % criterion. More iteration steps enhance the image further.
            % 
            % The user can also select from the Settings Tab if the iteration
            % is animated and if the progress is shown. Note that showing 
            % the progress and animating slows the iteration down a bit.
            %
            % The iteration can be aborted manually by pressing the StartButton 
            % during the iteration process. Note that it might take a while
            % for MATLAB to register the pushing event. After aborting the 
            % iteration, the image is plotted according to the finished 
            % iterations.
            %
            % See also IMAGE, SETAXES, SHOWITERATIONPROGRESS, INITSETTINGS.
            
            % Polynomial
            coeffs = app.Coeffs;
            roots = app.Roots;
            nroots = app.NRoots;
            
            % Iteration
            tol = app.Tol;
            bound = app.Bound;
            iterMax = app.IterMax;
            itersPerStep = app.ItersPerStep;
            iters = 0;
            app.Iters = iters;
            showProgress = app.ShowProgress;
            
            % Animation
            animate = app.Animate;
            wait = app.AnimWait;
            
            % Plotting
            re = app.Re; % Real axis
            im = app.Im; % Imaginary axis
            cMap = app.CMap;
            
            % Begin the iteration
            initVals = app.Grid;
            dims = size(initVals);
            ANS = initVals;
            C = (nroots+2)*ones(dims); % Indices for the colormap cMap
            
            image(app.UIAxes,re,im,C);
            set(app.UIAxes, 'colormap', cMap);
            app.UIAxes.YDir = 'normal';
            hold(app.UIAxes,'on')
            plot(app.UIAxes, roots,'o', ...
                'MarkerFaceColor','y','MarkerEdgeColor','k');
            hold(app.UIAxes,'off')
            
            app.setAxes; % DO NOT REMOVE!

            while isequal(app.State,'iterate')
                
                if iters >= iterMax
                    app.toggleState('abort')
                else

                    % Inner iteration step
                    for j = 1:itersPerStep
                        f = polyval(coeffs,ANS);
                        df = polyval(polyder(coeffs),ANS);
                        ANS = ANS - f./(df+eps); % Prevent division by zero
                    end

                    iters = iters + itersPerStep;
                    
                    if showProgress
                        app.showIterationProgress;
                        app.Iters = iters;
                    end

                    % Convergence
                    for j = 1:nroots
                        C(abs(ANS - roots(j)) < tol) = j;
                    end
                    
                    % Divergence
                    C(abs(ANS) > bound) = nroots + 1;
                   
                    if animate
                        pause(wait);
                        image(app.UIAxes,re,im,C);
                        set(app.UIAxes,'colormap',cMap)
                        app.UIAxes.YDir = 'normal';
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes, roots,'o', ...
                            'MarkerFaceColor','y','MarkerEdgeColor','k');
                        hold(app.UIAxes,'off')
                    end
                    
                end % if

            end % while
            
            image(app.UIAxes,re,im,C);
            set(app.UIAxes,'colormap',cMap)
            app.UIAxes.YDir = 'normal';
            hold(app.UIAxes,'on')
            plot(app.UIAxes, roots,'o', ...
                'MarkerFaceColor','y','MarkerEdgeColor','k');
            hold(app.UIAxes,'off')
            
            app.toggleState('ready');
            
        end % findAndPlotBasins
        
        function manageButtons(app,state)
            % MANAGEBUTTONS  Enable or disable GUI buttons.
            %
            % state = character array of the current state of the program
            %
            % The StartButton and ResetButtons are disabled if the program
            % is in an erroneous state. Axes can be reset only if the state
            % is ready. If the program is iterating the basins, the user
            % can press the StartButton again to abort the iterating
            % process manually.
            %
            % Note that StartButton displays its current functionality via 
            % its text.
            %
            % See also TOGGLESTATE.
            
            if ~isequal(state,'ready') && ~isequal(state,'iterate')
                app.StartButton.Enable = false;
                app.ResetAxesButton.Enable = false;
            elseif isequal(state,'iterate')
                app.StartButton.Enable = true;
                app.StartButton.Text = 'Abort';
                app.ResetAxesButton.Enable = false;
            else
                app.StartButton.Enable = true;
                app.StartButton.Text = 'Start';
                app.ResetAxesButton.Enable = true;
            end
            
        end % manageButtons
        
        function disableEditing(app,disable)
            % DISABLEEDITING  Disable or enable data editing.
            %
            % disable = boolean for disabling data editing
            %
            % Function prevents the user to edit any data. This includes
            % any changes to the polynomial, settings and colormap. Note
            % that the grid lines can be turned on and off during
            % iteration.
            % 
            % The goal is to prevent any user inflicted data errors in 
            % tables during the iteration which will cause it to abort.
            % Also changing the colormap will not work properly due to 
            % local variables in findAndPlotBasins.
            %
            % See also TOGGLESTATE.
            
            if disable
                app.DegreeSpinner.Enable = false;
                app.RandPolyButton.Enable = false;
                app.PolyTable.ColumnEditable = false;
                app.GridTable.ColumnEditable = false;
                app.IterTable.ColumnEditable = false;
                app.ColormapDropDown.Enable = false;
            else
                app.DegreeSpinner.Enable = true;
                app.RandPolyButton.Enable = true;
                app.PolyTable.ColumnEditable = true;
                app.GridTable.ColumnEditable = true;
                app.IterTable.ColumnEditable = true;
                app.ColormapDropDown.Enable = true;
            end
            
        end % disableEditing
        
        function showIterationProgress(app)
            % SHOWITERATIONPROGRESS  Display the iteration progress with
            % percentages.
            %
            % The progress is calculated simply as the ratio between
            % completed iterations and maximum number of iterations. The
            % results are displayed as whole number percentages.
            %
            % The progress is shown if the user has checked the box in the
            % Settings tab. The box must be (un)checked before the iterating
            % process starts. Any changes during iterations are discarded.
            %
            % Note that ReadyLampLabel is also modified via the function
            % toggleState. Furthermore, showing the iteration progress
            % slows down the iteration speed a bit. The pause at the end
            % enables MATLAB to update the label text correctly.
            %
            % See also TOGGLESTATE, FINDANDPLOTBASINS.
            
            progress = app.Iters / app.IterMax * 100;
            str = ['Iterating... ', num2str(progress,'%3.0f'),' % completed.'];
            app.ReadyLampLabel.Text = str;
            pause(0.001) % DO NOT REMOVE!
            
        end % showIterationProgress
        
        function setPolyStr(app)
            % SETPOLYSTR  Set the polynomial string to current polynomial.
            %
            % The current polynomial is interpreted from the coefficients
            % in descending order. Zero coefficients are discarded and only
            % the signs of 1 and -1 are displayed if they appear as a
            % coefficient. Constant terms are displayed if they are
            % non-zero.
            %
            % If the coefficient is a complex number, it is placed in 
            % parenthesis. All coefficients are displayed with at most one 
            % decimal points. Also latex format is stored for later use in 
            % text labels.
            %
            % Note that the label for displaying the current polynomial
            % cannot display more than six nonzero coefficients plus the 
            % constant term at a time. With more terms the polynomial will
            % not fit to the label. This is only a visual flaw and it does 
            % not affect the program execution.
            %
            % See also GETCOEFFSTR.

            polyData = app.Coeffs;
            exp = app.Degree;
            str = '';
            
            while exp > 0
                j = app.Degree - exp + 1;
                z = polyData(j); % Current coefficient
                a = real(z);
                b = imag(z);
                term = '';
                
                if z ~= 0
                    % Non-zero coefficient
                    
                    if a ~= 0 && b ~= 0
                        % Complex coefficient
                        term = app.getCoeffStr(z,exp,'complex');
                    else
                        if a == 0
                            % Imaginary coefficient
                            term = app.getCoeffStr(b,exp,'imaginary');
                            
                        elseif b == 0
                            % Real coefficient
                            term = app.getCoeffStr(a,exp,'real');
                        end
                    end
                end % if
                
                % Add the term to str
                str = strcat(str,term);
                exp = exp - 1;
                
            end % while
            
            % Constant term
            z = polyData(end);
            a = real(z);
            b = imag(z);
            realStr = num2str(a,'%.1f');
            imagStr = num2str(b,'%.1f');
            operator = '+';
            term = '';
            
            if z ~= 0
                % Non-zero constant term
                
                if b == 1
                    imagStr = '';
                elseif b == -1
                    imagStr = '-';
                end
                
                if a == 0
                    % Imaginary constant
                    if b < 0
                        operator = '';
                    end
                    term = strcat(operator,imagStr,'i');
                elseif b == 0
                    % Real constant
                    if a < 0
                        operator = '';
                    end
                    term = strcat(operator,realStr);
                else
                    % Complex constant
                    if b < 0
                        operator = '';
                    end
                    term = strcat(realStr,operator,imagStr,'i');
                    
                    if a > 0
                        operator = '+';
                    end
                    
                    term = strcat(operator,term);
                    
                end % if
              
            end % if
            
            % Add constant term to str
            str = strcat(str,term);
            
            % Check the sign of the first coefficient
            z = polyData(1);
            a = real(z);
            b = imag(z);
            
            if xor(a > 0, b > 0)
                str(1) = '';
            end
            
            app.PolyStr = str;
            app.PolyStrLtx = strcat('$',str,'$');
            
        end % setPolyStr
        
        function res = getCoeffStr(~,a,exp,type)
            % GETCOEFFSTR  Form a string from a polynomial coefficient.
            %
            % a = double coefficient
            % exp = integer exponent
            % type = character array coefficient type
            %
            % The type is either 'real', 'imaginary' or 'complex'. The
            % formatting is done according to the definitions in function
            % setPolyStr. Latex format is supported.
            %
            % Function returns the term a*z^exp in string format with
            % specifications determined by type.
            %
            % See also SETPOLYSTR.
            
            operator = '+';
            str = '';
            
            switch type
                case 'real'
                    if a < 0
                        operator = '';
                    end
                    
                    if a == 1
                        a = '';
                    elseif a == -1
                        a = '';
                        operator = '-';
                    end
                    
                    if exp ~= 1
                        str = [operator,num2str(a,'%.1f'), ...
                        'z^{',num2str(exp),'}'];
                    else
                         str = [operator,num2str(a,'%.1f'),'z'];
                    end
                    
                case 'imaginary'
                    if a < 0
                        operator = '';
                    end
                    
                    if a == 1
                        a = '';
                    elseif a == -1
                        a = '';
                        operator = '-';
                    end
                    
                    if exp ~= 1
                        str = [operator,num2str(a,'%.1f'),'i', ...
                        'z^{',num2str(exp),'}'];
                    else
                         str = [operator,num2str(a,'%.1f'),'i','z'];
                    end
                    
                case 'complex'
                    if exp ~= 1
                        str = [operator,'(',num2str(a,'%.1f'),')', ...
                        'z^{',num2str(exp),'}'];
                    else
                        str = [operator,'(',num2str(a,'%.1f'),')','z'];
                    end
            end % switch
            
            res = str;
            
        end % getCoeffStr

    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.toggleState('init');
            app.initPoly;
            app.initColor;
            app.initSettings;
            app.setAxes(true);
            app.toggleState('ready');
        end

        % Value changed function: DegreeSpinner
        function DegreeSpinnerValueChanged(app, event)
            value = app.DegreeSpinner.Value;
            change = value - app.Degree;
            app.Degree = value;
            app.adjustPolyTable(change);
            app.checkForDataErrorsAndUpdate;
        end

        % Button pushed function: RandPolyButton
        function RandPolyButtonPushed(app, event)
            dataSize = size(app.PolyTable.Data);
            
            % Generate random numbers between a and b.
            a = -5; 
            b = 5;
            app.PolyTable.Data = a + (b-a)*rand(dataSize);
            app.checkForDataErrorsAndUpdate;
        end

        % Cell edit callback: PolyTable
        function PolyTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.PolyTable.Data(indices(1)) = newData;
            app.checkForDataErrorsAndUpdate;
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.updateColorMap;
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
            else
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
            end
        end

        % Value changed function: MinorGridCheckBox
        function MinorGridCheckBoxValueChanged(app, event)
            value = app.MinorGridCheckBox.Value;
            if value
                app.UIAxes.XMinorGrid = 'on';
                app.UIAxes.YMinorGrid = 'on';
            else
                app.UIAxes.XMinorGrid = 'off';
                app.UIAxes.YMinorGrid = 'off';
            end
        end

        % Cell edit callback: GridTable
        function GridTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.GridTable.Data(indices(1),indices(2)) = newData;
            app.checkForDataErrorsAndUpdate;
        end

        % Value changed function: ShowprogressCheckBox
        function ShowprogressCheckBoxValueChanged(app, event)
            value = app.ShowprogressCheckBox.Value;
            app.ShowProgress = value;
        end

        % Cell edit callback: IterTable
        function IterTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.IterTable.Data(indices(1),indices(2)) = newData;
            app.checkForDataErrorsAndUpdate;
        end

        % Value changed function: AnimateCheckBox
        function AnimateCheckBoxValueChanged(app, event)
            value = app.AnimateCheckBox.Value;
            app.Animate = value;
        end

        % Value changed function: WaitSpinner
        function WaitSpinnerValueChanged(app, event)
            value = app.WaitSpinner.Value;
            app.AnimWait = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isequal(app.State,'ready')
                app.toggleState('iterate');
                app.findAndPlotBasins;
            else
                app.toggleState('abort');
            end
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.setAxes(true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasinsofAttractionUIFigure = uifigure('Visible', 'off');
            app.BasinsofAttractionUIFigure.Name = 'Basins of Attraction';
            app.GridLayout = uigridlayout(app.BasinsofAttractionUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PolynomialTab = uitab(app.TabGroup);
            app.PolynomialTab.Title = 'Polynomial';
            app.RandPolyButton = uibutton(app.PolynomialTab, 'push');
            app.RandPolyButton.ButtonPushedFcn = createCallbackFcn(app, @RandPolyButtonPushed, true);
            app.RandPolyButton.Tooltip = {'Generate random real coefficients.'};
            app.RandPolyButton.Text = 'Randomize';
            app.DegreeSpinnerLabel = uilabel(app.PolynomialTab);
            app.DegreeSpinnerLabel.Text = 'Degree';
            app.DegreeSpinner = uispinner(app.PolynomialTab);
            app.DegreeSpinner.Limits = [1 Inf];
            app.DegreeSpinner.RoundFractionalValues = 'on';
            app.DegreeSpinner.ValueDisplayFormat = '%.0f';
            app.DegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @DegreeSpinnerValueChanged, true);
            app.DegreeSpinner.Tooltip = {'Set the degree of the polynomial.'};
            app.DegreeSpinner.Value = 1;
            app.CurrentPolynomialLabel = uilabel(app.PolynomialTab);
            app.CurrentPolynomialLabel.Text = 'Current Polynomial:';
            app.PolyLabel = uilabel(app.PolynomialTab);
            app.PolyLabel.Interpreter = 'latex';
            app.PolyLabel.Tooltip = {'The polynomial interpreted from the given coefficients.'};
            app.PolyLabel.Text = '';
            app.PolyTable = uitable(app.PolynomialTab);
            app.PolyTable.ColumnName = {'Coefficients'};
            app.PolyTable.RowName = {};
            app.PolyTable.ColumnEditable = true;
            app.PolyTable.CellEditCallback = createCallbackFcn(app, @PolyTableCellEdit, true);
            app.PolyTable.Tooltip = {'Give the (complex) coefficients of the current polynomial starting from the highest order term. '};
            app.ColorsTab = uitab(app.TabGroup);
            app.ColorsTab.Title = 'Colors';
            app.BasinPanel = uipanel(app.ColorsTab);
            app.BasinPanel.Tooltip = {'Change the basin color of a root manually by pressing the corresponding color button on the right.'};
            app.RootsandcorrespondingBasinColorsLabel = uilabel(app.ColorsTab);
            app.RootsandcorrespondingBasinColorsLabel.Text = 'Roots and corresponding Basin Colors';
            app.IndeterminateColorLabel = uilabel(app.ColorsTab);
            app.IndeterminateColorLabel.Text = ' Indeterminate Color:';
            app.DivergenceColorLabel = uilabel(app.ColorsTab);
            app.DivergenceColorLabel.Text = 'Divergence Color:';
            app.RootColorLabel = uilabel(app.ColorsTab);
            app.RootColorLabel.Text = 'Root Color:';
            app.IndeterminateColorButton = uibutton(app.ColorsTab, 'push');
            app.IndeterminateColorButton.Text = '';
            app.DivergenceColorButton = uibutton(app.ColorsTab, 'push');
            app.DivergenceColorButton.Text = '';
            app.RootColorButton = uibutton(app.ColorsTab, 'push');
            app.RootColorButton.Text = '';
            app.ColormapDropDownLabel = uilabel(app.ColorsTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ColorsTab);
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Tooltip = {'Select the current colormap.'};
            app.ColormapDropDown.Value = {};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridTable = uitable(app.SettingsTab);
            app.GridTable.ColumnName = {'Min'; 'Max'; 'Grid Points'};
            app.GridTable.RowName = {};
            app.GridTable.ColumnEditable = true;
            app.GridTable.CellEditCallback = createCallbackFcn(app, @GridTableCellEdit, true);
            app.GridTable.Tooltip = {'Change the grid settings. Note that the lower limit must be less than the upper limit. Also, the number of grid points must be a positive whole number.'};
            app.GridCheckBox = uicheckbox(app.SettingsTab);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Tooltip = {'Grid lines on/off'};
            app.GridCheckBox.Text = 'Grid';
            app.MinorGridCheckBox = uicheckbox(app.SettingsTab);
            app.MinorGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinorGridCheckBoxValueChanged, true);
            app.MinorGridCheckBox.Tooltip = {'Minor grid lines on/off'};
            app.MinorGridCheckBox.Text = 'Minor Grid';
            app.IterTable = uitable(app.SettingsTab);
            app.IterTable.ColumnName = {'Value'};
            app.IterTable.RowName = {};
            app.IterTable.ColumnEditable = true;
            app.IterTable.CellEditCallback = createCallbackFcn(app, @IterTableCellEdit, true);
            app.IterTable.Tooltip = {'Change the iteration settings. Note that the tolerance for convergence must be less than the bound for divergence. Also, the iteration step must not be greater than the maximum number of iterations. All values must be positive.'};
            app.Panel = uipanel(app.SettingsTab);
            app.AnimateCheckBox = uicheckbox(app.Panel);
            app.AnimateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimateCheckBoxValueChanged, true);
            app.AnimateCheckBox.Tooltip = {'Animate the iteration process.'};
            app.AnimateCheckBox.Text = 'Animate';
            app.AnimateCheckBox.Value = true;
            app.WaitsecSpinnerLabel = uilabel(app.Panel);
            app.WaitsecSpinnerLabel.Text = 'Wait (sec)';
            app.WaitSpinner = uispinner(app.Panel);
            app.WaitSpinner.Step = 0.1;
            app.WaitSpinner.Limits = [0.1 10];
            app.WaitSpinner.ValueChangedFcn = createCallbackFcn(app, @WaitSpinnerValueChanged, true);
            app.WaitSpinner.Tooltip = {'Set the length of the animation loop.'};
            app.WaitSpinner.Value = 0.1;
            app.IterationSettingsLabel = uilabel(app.SettingsTab);
            app.IterationSettingsLabel.Text = 'Iteration Settings';
            app.AnimationSettingsLabel = uilabel(app.SettingsTab);
            app.AnimationSettingsLabel.Text = 'Animation Settings';
            app.GridSettingsLabel = uilabel(app.SettingsTab);
            app.GridSettingsLabel.Text = 'Grid Settings';
            app.ShowprogressCheckBox = uicheckbox(app.SettingsTab);
            app.ShowprogressCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowprogressCheckBoxValueChanged, true);
            app.ShowprogressCheckBox.Tooltip = {'Shows the iteration progress as a percentage of overall completion.'};
            app.ShowprogressCheckBox.Text = 'Show progress';
            app.Panel_2 = uipanel(app.GridLayout);
            app.ResetAxesButton = uibutton(app.Panel_2, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Tooltip = {'Reset the current axes.'};
            app.ResetAxesButton.Text = 'Reset Axes';
            app.StartButton = uibutton(app.Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Start calculating and plotting the basins of attraction or abort iteration process.'};
            app.StartButton.Text = 'Start';
            app.Panel_3 = uipanel(app.GridLayout);
            app.ReadyLampLabel = uilabel(app.Panel_3);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.Panel_3);
            app.ReadyLamp.Tooltip = {'The current state of the program. If there is an error, please follow the instructions.'};
            % Show the figure after all components are created
            app.BasinsofAttractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasinsOfAttraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasinsofAttractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasinsofAttractionUIFigure)
        end
    end
end