classdef coolingSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        AmbientTemperaturedegCEditFieldLabel  matlab.ui.control.Label
        AmbientTemperaturedegCEditField  matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        CoolingSimulationLabel        matlab.ui.control.Label
        TargetdegCminEditFieldLabel   matlab.ui.control.Label
        TargetdegCminEditField        matlab.ui.control.NumericEditField
        Switch                        matlab.ui.control.Switch
        TextArea                      matlab.ui.control.TextArea
        AddButton                     matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        HelpButton                    matlab.ui.control.Button
        HoldonCheckBox                matlab.ui.control.CheckBox
        InfoLabel                     matlab.ui.control.Label
        ShowtargetCheckBox            matlab.ui.control.CheckBox
        MaximumwaitingtimeminSpinnerLabel  matlab.ui.control.Label
        MaximumwaitingtimeminSpinner  matlab.ui.control.Spinner
        ImportdataButton              matlab.ui.control.Button
    end

    properties (Access = private)
        AmbientTemperature = 0; % Ambient temperature of the system (degC)
        maxTime = 240; % (min) Corresponds to 4h
        target = [0,0]; % Target temperature or time
        targetReachable = true; % Indicates if the target is reachable or not
        
        percentage = 0.01; % Percentage for tolerance
        extrapolate = 5; % Amount of extrapolating from data points
        N = 1000; % Amount of data points for linspace
        fitCoefficients = []; % Coefficients for the fit made with lsqcurvefit
        
        plotStyles = ["k--", "k:", "k-.", "k-"]; % Varies the plot for ambient temperature
        plotCounter = -1; % Decides which plot type will be used
        AxisLimits = []; % Limits for the axes
        
        legendArray = {}; % For identifying the different plots
        legendCounter = -1; % Keeps count of how many legends need to be printed
        targetPlotList = []; % Helps replotting the target lines
        
        helpMessage = ["Welcome to the Cooling Simulation!", ...
            "", ...
            "", ...
            "This app helps you to visualize the cooling of any object using Newton's Cooling Law. " + ...
            "In its differential form, the law looks like", ...
            "", ...
            "           dT/dt = -k(T-Ta)", ...
            "", ...
            "where dT/dt is the rate of change of the temperature T with respect to time t, k is a cooling constant " + ...
            "determined by the system, T is the temperature of the object and Ta is the ambient temperature.", ...
            "", ...
            "The solution is drawn by pressing the 'Calculate' button. Furthermore, " + ...
            "you can either import the data by pressing the 'Import data' button or add and remove data points with the simple" + ...
            " 'Add' and 'Remove' buttons. To calculate " + ...
            "the solution, you need at least two data points. The solution is calculated using the Least Squares method.", ...
            "", ...
            "The ambient temperature, maximum waiting time, target temperature and target waiting time can be " + ...
            "edited. Depending on the position of the Temperature/Time switch, either one can be treated as the target.", ...
            "", ...
            "By checking the 'Hold on' and 'Show target' checkboxes, you can affect the plotting of the solutions. The axes scale " + ...
            "automatically to the newest solution.", ...
            "", ...
            "Lastly, all important information is displayed in the 'Info' box. ",...
            "",...
            "",...
            "Have fun!",...
            "",...
            "Jasper Kosonen",...
            "Tampere, 2.7.2020"]; % Instructions message
    end
    
    methods (Access = private)
        
        function [] = calculateSolution(app)
            % Calculates the solution to the explicit form of Newton's
            % Cooling Law using the LS-method
            
            x0 = [20; 0.05]; % Initial guess
            t = lsqcurvefit(@app.NCL, x0, app.UITable.Data(:,1), app.UITable.Data(:,2)); % Solution with LSQ method
            app.fitCoefficients = t;
            
            duration = getDuration(app, t, app.AmbientTemperature); % Checking how long it takes to reach ambient temperature
            
            drawSolution(app, t, duration)
        end
        
        function duration = getDuration(app, t, target)
            % Calculates how long it takes to reach the target temperature
            % with the tolerance for error
            
            duration = 0;
            tolerance = app.percentage * target; % Cool down up to (app.percentage) of target temperature
            maxDuration = app.maxTime;
            while abs(NCL(app, t, duration) - target) > tolerance
                if duration == maxDuration % Maximum waiting time exceeded
                    break
                end
                
                duration = duration + 0.1;
                
            end
        end
        
        function [] = drawSolution(app, t, duration)
            % Plots the solution to the Axes
            
            app.legendCounter = app.legendCounter + 3; % Keeping track of the legends for hold on plot
            app.plotCounter = app.plotCounter + 1; % Helps changing the plot style for multiple ambient temperature lines
            
            % If not hold on, legends will reset
            if app.HoldonCheckBox.Value == 0
                app.legendArray = {};
                app.legendCounter = 1;
                app.plotCounter = 0;
                hold(app.UIAxes, 'off')
                app.UIAxes.cla
            end
            
            % Determining how far the fit will be drawn
            maxData = max(app.UITable.Data(:,1));
            if app.Switch.Value == "Time"
                if duration < app.target(1)
                    if maxData < app.target(1)
                        tdata = linspace(-app.extrapolate, app.target(1)+app.extrapolate, app.N);
                    else
                        tdata = linspace(-app.extrapolate, maxData+app.extrapolate, app.N);
                    end
                else
                    if maxData < duration
                        tdata = linspace(-app.extrapolate, duration+app.extrapolate, app.N);
                    else
                        tdata = linspace(-app.extrapolate, maxData+app.extrapolate, app.N);
                    end 
                end
            else
                if maxData < duration
                    tdata = linspace(-app.extrapolate, duration+app.extrapolate, app.N);
                else
                    tdata = linspace(-app.extrapolate, maxData+app.extrapolate, app.N);
                end
            end   
            
            ydata = NCL(app, t, tdata);
            
            [~, I] = max(abs(app.AmbientTemperature*ones(length(app.UITable.Data(:,2)), 1) - app.UITable.Data(:,2)));
            diff = app.AmbientTemperature - app.UITable.Data(I,2);
            coefficient = 0.25; % A scaling coefficient for axis
            epsilon = 0.000001; % For axis limits
            
            % Determining the limits for axes
            if diff <= 0
                axisLimit = [0 duration+epsilon app.AmbientTemperature-coefficient*abs(diff) app.AmbientTemperature+abs(diff)+1];
                axis(app.UIAxes, axisLimit)
            else
                axisLimit = [0 duration+epsilon app.AmbientTemperature-abs(diff)-1 app.AmbientTemperature+coefficient*abs(diff)];
                axis(app.UIAxes, axisLimit)
            end
            
            app.AxisLimits = axisLimit;
            plot(app.UIAxes, app.UITable.Data(:,1), app.UITable.Data(:,2), '.', 'MarkerSize', 20) % Data points
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, tdata, ydata) % Fit to data from lsqcurvefit
            
            % Drawing the target
            if app.Switch.Value == "Temperature"
                if checkTarget(app, t)
                    app.targetReachable = true;
                    
                    % Scaling the t axis properly
                    if duration <= app.target(1)
                        xlim(app.UIAxes, [0 app.target(1)+app.extrapolate])
                    end
                    
                    app.TextArea.Value = ["The target temperature of " + num2str(app.target(2), 3) + ...
                        " degC is reached after approximately " + num2str(round(app.target(1), 0)) + " minutes.", ...
                        "", ...
                        "The tolerance for error is " + num2str(app.percentage*100) + "% and the minutes are rounded to the nearest integer."];
                    targetPlot(app, t, axisLimit)
                else
                    % Erasing the target and resetting the plot list
                    app.targetReachable = false;
                    if length(app.targetPlotList) == 3
                        delete(app.targetPlotList(1))
                        delete(app.targetPlotList(2))
                        delete(app.targetPlotList(3))
                        app.targetPlotList = [];
                        
                    end
                end
            else
                app.targetReachable = true;
                if duration <= app.target(1)
                    xlim(app.UIAxes, [0 app.target(1)+app.extrapolate])
                end
                
                app.TextArea.Value = "After " + num2str(round(app.target(1), 0)) + " minutes, the temperature is " + ...
                    num2str(NCL(app, t, app.target(1)), 3) + " degC.";
                targetPlot(app, t, axisLimit)
            end
            
            yline(app.UIAxes, app.AmbientTemperature, app.plotStyles(1, mod(app.plotCounter, length(app.plotStyles))+1)) % Ambient temperature line
            
            % Storing the legends in a cyclic manner
            app.legendArray{1,app.legendCounter} = 'Data points';
            app.legendArray{1,app.legendCounter+1} = [num2str(app.AmbientTemperature, 3), ' + ', num2str(t(1), 3), 'exp(-', ...
                num2str(t(2), 3), 't) (degC)']; 
            app.legendArray{1,app.legendCounter+2} = ['Ambient temperature (', num2str(app.AmbientTemperature, 3), ' degC)'];
            
            legend(app.UIAxes, app.legendArray)
          
        end
        
        function fun = NCL(app, t, tdata)
            fun = app.AmbientTemperature + t(1)*exp(-t(2)*tdata); % Newton's Cooling Law in explicit form
        end
        
        function isValid = checkTarget(app, t)
            % Determines wheter the target is possible to reach in the
            % given maxTime or not
            
            if app.target(2) < app.AmbientTemperature && t(1) > 0
                isValid = false;
                app.TextArea.Value = "Cannot reach target temperature.";
                return
                
            elseif app.target(2) > app.AmbientTemperature && t(1) < 0 
                isValid = false;
                app.TextArea.Value = "Cannot reach target temperature.";
                return
                
            elseif app.target(2) <= app.AmbientTemperature && t(1) < 0
                if app.target(2) < NCL(app, t, 0) % The object cannot cool down
                    isValid = false;
                    app.TextArea.Value = "Cannot reach target temperature.";
                    return
                end
                app.target(1) = getDuration(app, t, app.target(2));
                if app.target(1) <= app.maxTime
                    isValid = true;
                    return
                else
                    isValid = false;
                    app.TextArea.Value = "Cannot reach target temperature in the given maximum waiting time.";
                    return
                end
                
            elseif app.target(2) >= app.AmbientTemperature && t(1) > 0
                if app.target(2) > NCL(app, t, 0) % The object cannot warm up
                    isValid = false;
                    app.TextArea.Value = "Cannot reach target temperature.";
                    return
                end
                app.target(1) = getDuration(app, t, app.target(2));
                if app.target(1) < app.maxTime % Target must be reachable within the set maximum waiting time
                    isValid = true;
                    return
                else
                    isValid = false;
                    app.TextArea.Value = "Cannot reach target temperature in the given maximum waiting time.";
                    return
                end
            end
        end
        
        function [] = targetPlot(app, t, axisLimit)
            % Helps to plot and delete the target plots
            
            targetRounded = round(app.target(1), 0); % The minutes are rounded to the nearest integer
            
            if app.ShowtargetCheckBox.Value == true
                yValue = NCL(app, t, targetRounded);
                
                % Deleting the previous target plots
                if length(app.targetPlotList) == 3
                    delete(app.targetPlotList(1))
                    delete(app.targetPlotList(2))
                    delete(app.targetPlotList(3))
                end
                
                p1 = plot(app.UIAxes, targetRounded, yValue, 'kx', 'MarkerSize', 20, ...
                    'HandleVisibility', "off");
                p2 = plot(app.UIAxes, [targetRounded, targetRounded], [axisLimit(3), yValue], ...
                    'k-', 'HandleVisibility', "off"); % vertical line
                p3 = plot(app.UIAxes, [axisLimit(1), targetRounded], [yValue, yValue], 'k-', ...
                    'HandleVisibility', "off"); % horizontal line
                
                app.targetPlotList(1) = p1;
                app.targetPlotList(2) = p2;
                app.targetPlotList(3) = p3;
                
            else % The target is not wanted to show
                
                % Deleting the target plots and resetting the target plot
                % list
                if length(app.targetPlotList) == 3
                    delete(app.targetPlotList(1))
                    delete(app.targetPlotList(2))
                    delete(app.targetPlotList(3))
                    app.targetPlotList = [];
                end
            end
        end
        
        function [] = checkInput(app)
            % Checking for erroneous inputs
            for i=1:size(app.UITable.Data, 2)
                for j=1:size(app.UITable.Data, 1)
                    if isnan(app.UITable.Data(j,i))
                        app.TextArea.Value = "Please check data.";
                        app.AddButton.Enable = false;
                        app.CalculateButton.Enable = false;
                        return
                    end
                end
            end
            
            app.AddButton.Enable = true;
            app.CalculateButton.Enable = true;
            
        end
        
        function [] = resetTextArea(app)
            % Resets the TextArea info box
            app.TextArea.Value = "";
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AmbientTemperaturedegCEditField.Value = 20;
            app.AmbientTemperaturedegCEditField.Limits = [0, Inf];
            app.AmbientTemperature = app.AmbientTemperaturedegCEditField.Value;
            
            app.UIAxes.Title.String = "Newton's Cooling Law";
            
            app.TargetdegCminEditField.Value = 25;
            app.target(2) = app.TargetdegCminEditField.Value;
            
            app.TextArea.Editable = false;
            
            app.UITable.Data = [0 34; 16 27]; % Example data
            app.UITable.ColumnEditable = true;
            
            app.ShowtargetCheckBox.Value = true;
            
            app.MaximumwaitingtimeminSpinner.Value = app.maxTime;
            app.MaximumwaitingtimeminSpinner.Limits = [0, Inf];
                      
            calculateSolution(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            resetTextArea(app)
            app.RemoveButton.Enable = true;
            app.UITable.Data(end+1,:) = 0; % Add row with zeros
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            resetTextArea(app)
            
            if size(app.UITable.Data, 1) > 2
                app.UITable.Data(end,:) = []; % Remove last row
            else
                app.RemoveButton.Enable = false;
                app.TextArea.Value = "Must have at least two data points.";
            end
            
            checkInput(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            resetTextArea(app)
            checkInput(app)
        end

        % Value changed function: AmbientTemperaturedegCEditField
        function AmbientTemperaturedegCEditFieldValueChanged(app, event)
            value = app.AmbientTemperaturedegCEditField.Value;
            app.AmbientTemperature = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculateSolution(app)
        end

        % Value changed function: TargetdegCminEditField
        function TargetdegCminEditFieldValueChanged(app, event)
            value = app.TargetdegCminEditField.Value;
            if app.Switch.Value == "Temperature"
                app.target(2) = value;
            else
                app.target(1) = value;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Temperature"
                app.TargetdegCminEditField.Limits = [-Inf, Inf];
                app.target(2) = app.TargetdegCminEditField.Value;
            else
                app.TargetdegCminEditField.Limits = [0, app.maxTime];
                app.target(1) = app.TargetdegCminEditField.Value;
            end
        end

        % Value changed function: MaximumwaitingtimeminSpinner
        function MaximumwaitingtimeminSpinnerValueChanged(app, event)
            value = app.MaximumwaitingtimeminSpinner.Value;
            app.maxTime = value;
            if app.Switch.Value == "Time"
                app.TargetdegCminEditField.Limits(2) = app.maxTime;
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msgbox(app.helpMessage, 'Instructions', 'help')
        end

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            app.TextArea.Value = ["Please select the .txt file that contains the numeric data in the form", ...
                "t1 T1", ...
                "t2 T2", ...
                "etc."];
            
            file = uigetfile('.txt');
            
            if file == 0
                app.TextArea.Value = "No data was selected.";
            else
                try
                    fileID = fopen(file, 'r');
                    formatSpec = '%f %f';
                    sizeA = [2 Inf];
                    A = fscanf(fileID, formatSpec, sizeA).';
                    fclose(fileID);
                catch
                    app.TextArea.Value = "Error in reading the file.";
                    return
                end
                
                if size(A,1) < 2
                    app.TextArea.Value = ["Must have at least two valid numeric data points.", ...
                        "", ...
                        "Please check data."];
                    return
                    
                else
                    
                    % Replacing the current data with data in A
                    app.UITable.Data = A;
                    
                    app.TextArea.Value = "Data read. Ignored any invalid data points.";
                end
            end
            
            checkInput(app)
        end

        % Value changed function: ShowtargetCheckBox
        function ShowtargetCheckBoxValueChanged(app, event)
            if app.targetReachable
                targetPlot(app, app.fitCoefficients, app.AxisLimits)   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Temperature (degC)')
            app.AmbientTemperaturedegCEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientTemperaturedegCEditFieldLabel.Text = 'Ambient Temperature (degC)';
            app.AmbientTemperaturedegCEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientTemperaturedegCEditField.ValueChangedFcn = createCallbackFcn(app, @AmbientTemperaturedegCEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time (min)'; 'Temperature (degC)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CoolingSimulationLabel = uilabel(app.UIFigure);
            app.CoolingSimulationLabel.Text = 'Cooling Simulation';
            app.TargetdegCminEditFieldLabel = uilabel(app.UIFigure);
            app.TargetdegCminEditFieldLabel.Text = 'Target (degC / min)';
            app.TargetdegCminEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetdegCminEditField.ValueChangedFcn = createCallbackFcn(app, @TargetdegCminEditFieldValueChanged, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Temperature', 'Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Temperature';
            app.TextArea = uitextarea(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.Text = 'Hold on';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Info:';
            app.ShowtargetCheckBox = uicheckbox(app.UIFigure);
            app.ShowtargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowtargetCheckBoxValueChanged, true);
            app.ShowtargetCheckBox.Text = 'Show target';
            app.MaximumwaitingtimeminSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumwaitingtimeminSpinnerLabel.Text = 'Maximum waiting time (min)';
            app.MaximumwaitingtimeminSpinner = uispinner(app.UIFigure);
            app.MaximumwaitingtimeminSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumwaitingtimeminSpinnerValueChanged, true);
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coolingSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end